classdef DataSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataTester                    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        NoiseKnobLabel                matlab.ui.control.Label
        NoiseKnob                     matlab.ui.control.Knob
        UndersamplingKnobLabel        matlab.ui.control.Label
        UndersamplingKnob             matlab.ui.control.Knob
        UndersamplingSpreadKnobLabel  matlab.ui.control.Label
        UndersamplingSpreadKnob       matlab.ui.control.DiscreteKnob
        AnatomyKnobLabel              matlab.ui.control.Label
        AnatomyKnob                   matlab.ui.control.Knob
        TipKnobLabel                  matlab.ui.control.Label
        TipKnob                       matlab.ui.control.DiscreteKnob
        ThicknessLabel                matlab.ui.control.Label
        ThicknessKnob                 matlab.ui.control.Knob
        PlotButton                    matlab.ui.control.Button
        ImageheightEditFieldLabel     matlab.ui.control.Label
        ImageheightEditField          matlab.ui.control.NumericEditField
        ImagewidthEditFieldLabel      matlab.ui.control.Label
        ImagewidthEditField           matlab.ui.control.NumericEditField
        DownloaddatasetButton         matlab.ui.control.Button
        SizedatasetEditFieldLabel     matlab.ui.control.Label
        SizedatasetEditField          matlab.ui.control.NumericEditField
        RightPanel                    matlab.ui.container.Panel
        DataPlotUIAxes                matlab.ui.control.UIAxes
        Label_3                       matlab.ui.control.Label
        PlotTypeSwitch                matlab.ui.control.Switch
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        autoUpdate
    end
    
    properties (Access = public)
        pts 
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            addpath('util/')

            im_height = app.ImageheightEditField.Value ;
            im_width = app.ImagewidthEditField.Value ;
            im_size = [im_width im_height] ; 
            blur = app.NoiseKnob.Value ;
            thickness = round(app.ThicknessKnob.Value); 
            anatomy = app.AnatomyKnob.Value; 
            undersampling = app.UndersamplingKnob.Value ;
            undersampling_spread = str2num(app.UndersamplingSpreadKnob.Value) ; 
            tip_current = str2num(app.TipKnob.Value);

            plotType = app.PlotTypeSwitch.Value ;
            
            [ground_truth, simulated_data] = simulate(app.pts, im_size, blur, thickness, undersampling, undersampling_spread, tip_current, 0);
     
  
            if strcmp(plotType,'Data')
               imshow(simulated_data,'Parent',app.DataPlotUIAxes);
            else
               imshow(ground_truth,'Parent',app.DataPlotUIAxes);
            end            
            
        end
      
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlotTypeSwitch.Value = 'Data' ;
            app.PlotButton.Enable = 'on' ;
            app.autoUpdate = 1 ;
            im_height = app.ImageheightEditField.Value ;
            im_width = app.ImagewidthEditField.Value ;
            im_size = [im_width im_height] ; 
            [app.pts, spline_f] = gen_spline_realistic(im_size, 100, 0.75);
            updatePlot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DataTester.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {492, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            im_height = app.ImageheightEditField.Value ;
            im_width = app.ImagewidthEditField.Value ;
            im_size = [im_width im_height] ; 
            [app.pts, spline_f] = gen_spline_realistic(im_size, 100, 0.75);
            updatePlot(app)
        end

        % Callback function
        function AutoUpdateSwitchValueChanged(app, event)
            if strcmp(app.AutoUpdateSwitch.Value, 'on')
                app.autoUpdate = 1 ;
                app.PlotButton.Enable = 'off' ;
                app.AutoUpdateLamp.Color = [0 1 0] ;
            else
                app.autoUpdate = 0 ;
                app.PlotButton.Enable = 'on' ;
                app.AutoUpdateLamp.Color = [0.5 0.5 0.5] ;
            end        
        end

        % Value changed function: AnatomyKnob, 
        % ImageheightEditField, NoiseKnob, PlotTypeSwitch, TipKnob, 
        % UndersamplingKnob, UndersamplingSpreadKnob
        function KnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Button pushed function: DownloaddatasetButton
        function DownloaddatasetButtonPushed(app, event)
            value = app.SizedatasetEditField.Value;
            dataset_X = zeros([value, im_size]);
            dataset_y = zeros([value, im_size]);
            parfor i = 1:value
                [gt, im] = simulate([128 64], 1.3, 1, 1.5, 10, 0, 0);
            
                dataset_X(i, :, :) = im;
                dataset_y(i, :, :) = gt;
            end
            dataset = [reshape(dataset_X, [1, size(dataset_X)]); reshape(dataset_y, [1, size(dataset_y)])];
            save('iteration_1_dataset.mat','dataset');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataTester = uifigure('Visible', 'off');
            app.DataTester.Name = 'Data Simulator';
            app.DataTester.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DataTester);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.NoiseKnobLabel = uilabel(app.GridLayout2);
            app.NoiseKnobLabel.Text = 'Noise';
            app.NoiseKnob = uiknob(app.GridLayout2, 'continuous');
            app.NoiseKnob.Limits = [0 4];
            app.NoiseKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.NoiseKnob.Value = 1;
            app.UndersamplingKnobLabel = uilabel(app.GridLayout2);
            app.UndersamplingKnobLabel.Text = 'Undersampling';
            app.UndersamplingKnob = uiknob(app.GridLayout2, 'continuous');
            app.UndersamplingKnob.Limits = [0.5 3];
            app.UndersamplingKnob.MajorTicks = [0 0.5 1 1.5 2 2.5 3];
            app.UndersamplingKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.UndersamplingKnob.Value = 0.5;
            app.UndersamplingSpreadKnobLabel = uilabel(app.GridLayout2);
            app.UndersamplingSpreadKnobLabel.Text = 'UndersamplingSpread';
            app.UndersamplingSpreadKnob = uiknob(app.GridLayout2, 'discrete');
            app.UndersamplingSpreadKnob.Items = {'5', '10', '20 ', '30 ', '40 ', '50', '100'};
            app.UndersamplingSpreadKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.UndersamplingSpreadKnob.Value = '5';
            app.AnatomyKnobLabel = uilabel(app.GridLayout2);
            app.AnatomyKnobLabel.Text = 'Anatomy';
            app.AnatomyKnob = uiknob(app.GridLayout2, 'continuous');
            app.AnatomyKnob.Limits = [0 1];
            app.AnatomyKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.AnatomyKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.TipKnobLabel = uilabel(app.GridLayout2);
            app.TipKnobLabel.Text = 'Tip';
            app.TipKnob = uiknob(app.GridLayout2, 'discrete');
            app.TipKnob.Items = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1.0'};
            app.TipKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.TipKnob.Value = '0.2';
            app.ThicknessLabel = uilabel(app.GridLayout2);
            app.ThicknessLabel.Text = 'Thickness';
            app.ThicknessKnob = uiknob(app.GridLayout2, 'continuous');
            app.ThicknessKnob.Limits = [1 5];
            app.ThicknessKnob.MajorTicks = [0 1 2 3 4 5];
            app.ThicknessKnob.MinorTicks = [0 1 2 3 4 5];
            app.ThicknessKnob.Value = 1;
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.ImageheightEditFieldLabel = uilabel(app.LeftPanel);
            app.ImageheightEditFieldLabel.Text = 'Image height';
            app.ImageheightEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ImageheightEditField.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.ImageheightEditField.Value = 500;
            app.ImagewidthEditFieldLabel = uilabel(app.LeftPanel);
            app.ImagewidthEditFieldLabel.Text = 'Image width';
            app.ImagewidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ImagewidthEditField.Value = 500;
            app.DownloaddatasetButton = uibutton(app.LeftPanel, 'push');
            app.DownloaddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DownloaddatasetButtonPushed, true);
            app.DownloaddatasetButton.Enable = 'off';
            app.DownloaddatasetButton.Text = 'Download dataset';
            app.SizedatasetEditFieldLabel = uilabel(app.LeftPanel);
            app.SizedatasetEditFieldLabel.Text = 'Size dataset';
            app.SizedatasetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SizedatasetEditField.Value = 20;
            app.RightPanel = uipanel(app.GridLayout);
            app.DataPlotUIAxes = uiaxes(app.RightPanel);
            xlabel(app.DataPlotUIAxes, 'X')
            ylabel(app.DataPlotUIAxes, 'Y')
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '';
            app.PlotTypeSwitch = uiswitch(app.RightPanel, 'slider');
            app.PlotTypeSwitch.Items = {'Data', 'Ground Truth'};
            app.PlotTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.PlotTypeSwitch.Value = 'Data';
            % Show the figure after all components are created
            app.DataTester.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataTester)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataTester)
        end
    end
end