classdef DataSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataTester                    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        NoiseKnobLabel                matlab.ui.control.Label
        NoiseKnob                     matlab.ui.control.Knob
        UndersamplingKnobLabel        matlab.ui.control.Label
        UndersamplingKnob             matlab.ui.control.Knob
        UndersamplingSpreadKnobLabel  matlab.ui.control.Label
        UndersamplingSpreadKnob       matlab.ui.control.DiscreteKnob
        RatioanatomyLabel             matlab.ui.control.Label
        RatioanatomyKnob              matlab.ui.control.Knob
        TipKnobLabel                  matlab.ui.control.Label
        TipKnob                       matlab.ui.control.DiscreteKnob
        SizeanatomyKnob               matlab.ui.control.Knob
        NoiseKnobLabel_2              matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        SizedatasetEditFieldLabel     matlab.ui.control.Label
        SizedatasetEditField          matlab.ui.control.NumericEditField
        widthEditFieldLabel           matlab.ui.control.Label
        widthEditField                matlab.ui.control.NumericEditField
        heightEditField_2Label        matlab.ui.control.Label
        heightEditField_2             matlab.ui.control.NumericEditField
        RightPanel                    matlab.ui.container.Panel
        DataPlotUIAxes                matlab.ui.control.UIAxes
        Label_3                       matlab.ui.control.Label
        PlotTypeSwitch                matlab.ui.control.Switch
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        autoUpdate
    end
    
    properties (Access = public)
        pts 
        blur
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            addpath('util/')
            im_height = app.widthEditField.Value ; 
            im_width = app.heightEditField_2.Value ; 
            im_size = [im_width im_height] ; 
            sigma = app.NoiseKnob.Value ;
            anatomy_ratio = app.RatioanatomyKnob.Value; 
            anatomy_size = app.SizeanatomyKnob.Value;
            undersampling = app.UndersamplingKnob.Value ;
            undersampling_spread = str2num(app.UndersamplingSpreadKnob.Value) ; 
            tip_current = str2num(app.TipKnob.Value);

            plotType = app.PlotTypeSwitch.Value ;
            
            [ground_truth, simulated_data] = simulate(app.pts, im_size, app.blur, 1, 0,0,...
                [sigma sigma], [tip_current tip_current],[undersampling undersampling],... 
                [undersampling_spread undersampling_spread], [anatomy_size anatomy_size], [anatomy_ratio anatomy_ratio]);
     
            if strcmp(plotType,'Data')
               imshow(simulated_data,'Parent',app.DataPlotUIAxes);
            else
               imshow(ground_truth,'Parent',app.DataPlotUIAxes);
            end            
            
        end
      
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            im_height = app.widthEditField.Value ; 
            im_width = app.heightEditField_2.Value ;             
            im_size = [im_width im_height] ; 
            app.PlotTypeSwitch.Value = 'Data' ;
            app.PlotButton.Enable = 'on' ;
            app.autoUpdate = 1 ;
            [app.pts, spline_f] = gen_spline_realistic(im_size, 100, 0.75);
            app.blur = gen_blur_filter(250, 1.3);
            updatePlot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DataTester.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {492, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            im_height = app.widthEditField.Value ; 
            im_width = app.heightEditField_2.Value ; 
            im_size = [im_width im_height] ; 
            [app.pts, spline_f] = gen_spline_realistic(im_size, 100, 0.75);
            updatePlot(app)
        end

        % Callback function
        function AutoUpdateSwitchValueChanged(app, event)
            if strcmp(app.AutoUpdateSwitch.Value, 'on')
                app.autoUpdate = 1 ;
                app.PlotButton.Enable = 'off' ;
                app.AutoUpdateLamp.Color = [0 1 0] ;
            else
                app.autoUpdate = 0 ;
                app.PlotButton.Enable = 'on' ;
                app.AutoUpdateLamp.Color = [0.5 0.5 0.5] ;
            end        
        end

        % Value changed function: NoiseKnob, PlotTypeSwitch, 
        % RatioanatomyKnob, TipKnob, UndersamplingKnob, 
        % UndersamplingSpreadKnob
        function KnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Callback function
        function DownloaddatasetButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataTester = uifigure('Visible', 'off');
            app.DataTester.Name = 'Data Simulator';
            app.DataTester.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DataTester);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.NoiseKnobLabel = uilabel(app.GridLayout2);
            app.NoiseKnobLabel.Text = 'Noise';
            app.NoiseKnob = uiknob(app.GridLayout2, 'continuous');
            app.NoiseKnob.Limits = [0 0.5];
            app.NoiseKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.NoiseKnob.Value = 0.1;
            app.UndersamplingKnobLabel = uilabel(app.GridLayout2);
            app.UndersamplingKnobLabel.Text = 'Undersampling';
            app.UndersamplingKnob = uiknob(app.GridLayout2, 'continuous');
            app.UndersamplingKnob.Limits = [0 2];
            app.UndersamplingKnob.MajorTicks = [0 0.5 1 1.5 2 2.5 3];
            app.UndersamplingKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.UndersamplingKnob.Value = 0.5;
            app.UndersamplingSpreadKnobLabel = uilabel(app.GridLayout2);
            app.UndersamplingSpreadKnobLabel.Text = 'UndersamplingSpread';
            app.UndersamplingSpreadKnob = uiknob(app.GridLayout2, 'discrete');
            app.UndersamplingSpreadKnob.Items = {'6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.UndersamplingSpreadKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.UndersamplingSpreadKnob.Value = '6';
            app.RatioanatomyLabel = uilabel(app.GridLayout2);
            app.RatioanatomyLabel.Text = 'Ratio anatomy';
            app.RatioanatomyKnob = uiknob(app.GridLayout2, 'continuous');
            app.RatioanatomyKnob.Limits = [0 1];
            app.RatioanatomyKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.RatioanatomyKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.TipKnobLabel = uilabel(app.GridLayout2);
            app.TipKnobLabel.Text = 'Tip';
            app.TipKnob = uiknob(app.GridLayout2, 'discrete');
            app.TipKnob.Items = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1.0'};
            app.TipKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.TipKnob.Value = '1.0';
            app.SizeanatomyKnob = uiknob(app.GridLayout2, 'continuous');
            app.SizeanatomyKnob.Limits = [0 1];
            app.NoiseKnobLabel_2 = uilabel(app.GridLayout2);
            app.NoiseKnobLabel_2.Text = 'Size anatomy';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SizedatasetEditFieldLabel = uilabel(app.LeftPanel);
            app.SizedatasetEditFieldLabel.Text = 'Size dataset';
            app.SizedatasetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SizedatasetEditField.Value = 20;
            app.widthEditFieldLabel = uilabel(app.LeftPanel);
            app.widthEditFieldLabel.Text = {'width'; ''};
            app.widthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.widthEditField.RoundFractionalValues = 'on';
            app.widthEditField.ValueDisplayFormat = '%.0f';
            app.widthEditField.Value = 192;
            app.heightEditField_2Label = uilabel(app.LeftPanel);
            app.heightEditField_2Label.Text = {'height'; ''};
            app.heightEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.heightEditField_2.RoundFractionalValues = 'on';
            app.heightEditField_2.ValueDisplayFormat = '%.0f';
            app.heightEditField_2.Value = 256;
            app.RightPanel = uipanel(app.GridLayout);
            app.DataPlotUIAxes = uiaxes(app.RightPanel);
            xlabel(app.DataPlotUIAxes, 'X')
            ylabel(app.DataPlotUIAxes, 'Y')
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '';
            app.PlotTypeSwitch = uiswitch(app.RightPanel, 'slider');
            app.PlotTypeSwitch.Items = {'Data', 'Ground Truth'};
            app.PlotTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.PlotTypeSwitch.Value = 'Data';
            app.ContextMenu = uicontextmenu(app.DataTester);
            % Assign app.ContextMenu
            app.Panel.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.DataTester.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataTester)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataTester)
        end
    end
end